classdef Financial < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
    end

    
    methods (Access = public)
        
        function [money, monthly] = qfunc(a, money, monthly)
if a == 1
    disp("q1 test");
    disp("Please use the slider to pick your price range for an apartment.");
    disp("The median price for a 1-bdrm apartment in Evanston is $1,195.");
    p = input("Price: ");
    % replace above with a slider using the game code // Leo
    money = money - p;
    monthly = monthly + p/12;
    disp(money);
elseif a == 2
    disp("q2 test");
    disp("Would you like to take the bus (1), or use a car? (2)")
    d = input("decision: "); %replace with a menu
    if d == 1
        disp("bus taken");
    else
        disp("car taken");
    end
else
    disp("qx test");
%     answer = input("Test1 answer: "); 
%     if answer == 1
%         monthly = monthly + 5;
%     else
%         money = money - 10;
%     end
end

        end
            
        end
    end
    

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Financial

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end